classdef usbl < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        mEditField               matlab.ui.control.NumericEditField
        mEditFieldLabel          matlab.ui.control.Label
        Button                   matlab.ui.control.Button
        target_ZmEditField       matlab.ui.control.NumericEditField
        target_ZmEditFieldLabel  matlab.ui.control.Label
        target_YmEditField       matlab.ui.control.NumericEditField
        target_YmEditFieldLabel  matlab.ui.control.Label
        target_XmEditField       matlab.ui.control.NumericEditField
        target_XmEditFieldLabel  matlab.ui.control.Label
        EditField_4              matlab.ui.control.NumericEditField
        Label_4                  matlab.ui.control.Label
        EditField_3              matlab.ui.control.NumericEditField
        Label_3                  matlab.ui.control.Label
        sEditField               matlab.ui.control.NumericEditField
        Label_2                  matlab.ui.control.Label
        msEditField              matlab.ui.control.NumericEditField
        Label                    matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
            element_nom = [0, 0, 0;
                   0.15, 0, 0;
                   0, 0.15, 0];
    freq = 30e3;
    c0 = 1500;
    
    end
    
    methods (Access = private)
        
        function p_est = estimatePosition(app, target, element_nom, ...
            sigma_base, sigma_tof, sigma_phase, sigma_c, c_nom)

        c = c_nom + sigma_c * randn();
        lambda = c / app.freq;
        L = 0.15;

        R_true = norm(target);
        u_true = target / R_true;

        element_act = element_nom + sigma_base * L * randn(size(element_nom));

        % 实际基线
        b12 = element_act(2,:) - element_act(1,:);
        b13 = element_act(3,:) - element_act(1,:);

        % 理论相位差
        delta_phi_true_x = (2*pi / lambda) * dot(b12, u_true);
        delta_phi_true_y = (2*pi / lambda) * dot(b13, u_true);

        % 加入误差
        delta_phi_meas_x = delta_phi_true_x + sigma_phase * randn();
        delta_phi_meas_y = delta_phi_true_y + sigma_phase * randn();

        % 名义基线
        b12_nom = element_nom(2,:) - element_nom(1,:);
        b13_nom = element_nom(3,:) - element_nom(1,:);
        Lx_nom = norm(b12_nom);
        Ly_nom = norm(b13_nom);

        u_x = (lambda / (2*pi)) * delta_phi_meas_x / Lx_nom;
        u_y = (lambda / (2*pi)) * delta_phi_meas_y / Ly_nom;

        sign_z = sign(target(3));
        u_z = sign_z * sqrt(max(0, 1 - u_x^2 - u_y^2));
        u_est = [u_x; u_y; u_z];

        t_true = R_true / c_nom;
        t_meas = t_true + sigma_tof * randn()*0.000001;
        R_meas = c * t_meas;

        p_est = R_meas * u_est;
    end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
    rng(0);
    sigma_c     = app.msEditField.Value;       % σ_c
    sigma_tof   = app.sEditField.Value;        % σ_tof
    sigma_base  = app.EditField_3.Value;       % σ_base
    sigma_phase = deg2rad(app.EditField_4.Value);  % σ_phase (converted from degrees to radians)

    target_x = app.target_XmEditField.Value;
    target_y = app.target_YmEditField.Value;
    target_z = app.target_ZmEditField.Value;
    target_true = [target_x; target_y; target_z];

    % 模拟次数
    N = 1000;
    estimates = zeros(3, N);

    % 进行 N 次估计
    for i = 1:N
        estimates(:, i) = estimatePosition(app, target_true, app.element_nom, ...
            sigma_base, sigma_tof, sigma_phase, sigma_c, app.c0);
    end

    % 取平均值
    p_est_mean = mean(estimates, 2);

    % 绘图
    cla(app.UIAxes);
    hold(app.UIAxes, 'on');
    plot3(app.UIAxes, app.element_nom(:,1), app.element_nom(:,2), app.element_nom(:,3), 'bo', 'DisplayName', '阵元');
    plot3(app.UIAxes, target_true(1), target_true(2), target_true(3), 'rx', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', '目标');
    plot3(app.UIAxes, p_est_mean(1), p_est_mean(2), p_est_mean(3), 'gx', 'MarkerSize', 10, 'LineWidth', 2, 'DisplayName', '估计均值');
    legend(app.UIAxes, 'Location', 'best');
    xlabel(app.UIAxes, 'X');
    ylabel(app.UIAxes, 'Y');
    zlabel(app.UIAxes, 'Z');
    grid(app.UIAxes, 'on');
    view(app.UIAxes, 3);

    % 计算估计均值与真实目标之间的欧几里得距离（误差）
    error_distance = norm(p_est_mean - target_true);

    app.mEditField.Value = error_distance;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'USBL定位')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '声速误差(m/s)';
            app.msEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '测时误差(μs)';
            app.sEditField = uieditfield(app.UIFigure, 'numeric');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '基线误差(%)';
            app.EditField_3 = uieditfield(app.UIFigure, 'numeric');
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '测相误差(°)';
            app.EditField_4 = uieditfield(app.UIFigure, 'numeric');
            app.target_XmEditFieldLabel = uilabel(app.UIFigure);
            app.target_XmEditFieldLabel.Text = 'target_X(m)';
            app.target_XmEditField = uieditfield(app.UIFigure, 'numeric');
            app.target_YmEditFieldLabel = uilabel(app.UIFigure);
            app.target_YmEditFieldLabel.Text = 'target_Y(m)';
            app.target_YmEditField = uieditfield(app.UIFigure, 'numeric');
            app.target_ZmEditFieldLabel = uilabel(app.UIFigure);
            app.target_ZmEditFieldLabel.Text = 'target_Z(m)';
            app.target_ZmEditField = uieditfield(app.UIFigure, 'numeric');
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '计算';
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = '误差(m)';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = usbl

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end